classdef Aplikacia < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Label           matlab.ui.control.Label
        TextArea        matlab.ui.control.TextArea
        UIAxes          matlab.ui.control.UIAxes
        ButtonPlay      matlab.ui.control.StateButton
        ButtonRecord    matlab.ui.control.StateButton
        ButtonPLOT      matlab.ui.control.StateButton
        EditFieldLabel  matlab.ui.control.Label
        EditField       matlab.ui.control.NumericEditField
        ButtonExit      matlab.ui.control.StateButton
        UIAxes2         matlab.ui.control.UIAxes
        ButtonOpenFile  matlab.ui.control.StateButton
    end


    properties (Access = public)
        plac=0 % Description
        ubm1=0 % Description
        smiech=0 % Description
        ubm2=0 % Description
        x % Description
        fs % Description
        y % Description
        player % Description
        flagg=2; % Description
    end

    methods (Access = public)
    
        function  vykresli(app)
            if isplaying(app) == 0
              app.ButtonPlay.Icon='02.png';
            end 
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [app.x,app.fs]=audioread('initializationAudio.wav');
            app.player = audioplayer(app.x, app.fs);
            
            
            app.ButtonPLOT.Enable='off';
            
            %inicializacia obrazku
             title(app.UIAxes, []);
                   xlabel(app.UIAxes, []);
                   ylabel(app.UIAxes, []);
                   app.UIAxes.XAxis.TickLabels = {};
                   app.UIAxes.YAxis.TickLabels = {};
                   % Display image and stretch to fill axes
                   I = imshow('bielePozadie.png', 'Parent', app.UIAxes, ...
                    'XData', [1 app.UIAxes.Position(3)], ...
                    'YData', [1 app.UIAxes.Position(4)]);
                   % Set limits of axes
                   app.UIAxes.XLim = [0 I.XData(2)];
                   app.UIAxes.YLim = [0 I.YData(2)];
                   
                 
                 vypis{1,1}='In order to start the recognition process select a file or start recording (red button)';
               
                 app.TextArea.Value=vypis{1,1};
                
        end

        % Value changed function: ButtonExit
        function ButtonExitValueChanged(app, event)
            value = app.ButtonExit.Value;
           
                selection = questdlg('Do you want to close the app?',...
                    '',...
                    'Yes','No','Yes');
                switch selection
                    case 'Yes'
                        delete(app.UIFigure)
                    case 'No'
                return
                end
           
        end

        % Value changed function: ButtonOpenFile
        function ButtonOpenFileValueChanged(app, event)
            %value = app.ButtonOpenFile.Value;
            
                 app.TextArea.Value={''};
                 app.ButtonPLOT.Enable='off';
             %inicializacia obrazku
             title(app.UIAxes, []);
                   xlabel(app.UIAxes, []);
                   ylabel(app.UIAxes, []);
                   app.UIAxes.XAxis.TickLabels = {};
                   app.UIAxes.YAxis.TickLabels = {};
                   % Display image and stretch to fill axes
                   I = imshow('bielePozadie.png', 'Parent', app.UIAxes, ...
                    'XData', [1 app.UIAxes.Position(3)], ...
                    'YData', [1 app.UIAxes.Position(4)]);
                   % Set limits of axes
                   app.UIAxes.XLim = [0 I.XData(2)];
                   app.UIAxes.YLim = [0 I.YData(2)];
            
            [pathname,filename,multiselect]= nacteni ();
            
            for u = 1:multiselect
            [app.x, app.fs] = audioread( fullfile( pathname, filename{u}));
            
            audiowrite('audio.wav',app.x,app.fs)


            vypis{1,1}='...file selected';
            app.TextArea.Value=vypis{1,1};
            vypis{2,1}='..recognition is in progress';
            app.TextArea.Value=vypis{2,1};            
            
            P1=(sum(abs(app.x).^2))*(1/length(app.x));
            if P1<=1e-5
    vypis{3,1}='The system cannot recognize the sound';
    app.TextArea.Value=vypis;
            else
    
    test_features=melcepst(app.x,16000,'dD');
    [X,test_features1,stredna_hodnota,smerodajna_odchylka] = CMN(test_features);
    
                    
                    load('celkovy_plac_32.mat');
                    likelihoodgm1 = gmmprob(gmm_adapt1,test_features1);
                        clear gmm_adapt1
                    load('celkovy_smiech_32.mat');
                    likelihoodgm2 = gmmprob(gmm_adapt1,test_features1);
                        clear gmm_adapt1
            
                    load('gmm_UBM_32.mat')
                    likelihoodgm3 = gmmprob(gm1_UBM,test_features1);
            
                    pomer=(likelihoodgm1./likelihoodgm3);
               
                   app.plac=0;
                    app.ubm1=0;
                    for i=1:length(pomer)
                        if pomer(i)>=1.7333
                            app.plac=app.plac+1;
                        else
                            app.ubm1=app.ubm1+1;
        
                        end
                    end
              
                    app.plac=(app.plac/length(pomer))*100;
                    app.ubm1=(app.ubm1/length(pomer))*100;


                    clear pomer
            
                    pomer=(likelihoodgm2./likelihoodgm3);
    
                   app.smiech=0;
                    app.ubm2=0;
                    for i=1:length(pomer)
                        if pomer(i)>=1.6714
                            app.smiech=app.smiech+1;
                        else
                            app.ubm2=app.ubm2+1;
        
                        end
                   end
              
                    app.smiech=(app.smiech/length(pomer))*100;
                    app.ubm2=(app.ubm2/length(pomer))*100;
            
            end 
            
        pocitadlo1=0;
        pocitadlo2=0;
        pocitadlo3=0;
            
              if (app.plac > app.ubm1) && (app.smiech > app.ubm2)
                if app.plac>app.smiech
                    pocitadlo1=pocitadlo1+1; %bol to plac
                    vypis{3,1}='It was the sound of crying';
                    app.TextArea.Value=vypis;
                    
                    title(app.UIAxes, []);
                    xlabel(app.UIAxes, []);
                    ylabel(app.UIAxes, []);
                    app.UIAxes.XAxis.TickLabels = {};
                    app.UIAxes.YAxis.TickLabels = {};
                    % Display image and stretch to fill axes
                    I = imshow('maxresdefault.jpg', 'Parent', app.UIAxes, ...
                        'XData', [1 app.UIAxes.Position(3)], ...
                        'YData', [1 app.UIAxes.Position(4)]);
                    % Set limits of axes
                    app.UIAxes.XLim = [0 I.XData(2)];
                    app.UIAxes.YLim = [0 I.YData(2)];
                else
                    pocitadlo2=pocitadlo2+1; %bol to smiech
                    vypis{3,1}='It was the sound of laugh';
                    app.TextArea.Value=vypis;
                    
                    title(app.UIAxes, []);
                    xlabel(app.UIAxes, []);
                    ylabel(app.UIAxes, []);
                    app.UIAxes.XAxis.TickLabels = {};
                    app.UIAxes.YAxis.TickLabels = {};
                    % Display image and stretch to fill axes
                    I = imshow('laughing-baby_postcard.jpg', 'Parent', app.UIAxes, ...
                    'XData', [1 app.UIAxes.Position(3)], ...
                    'YData', [1 app.UIAxes.Position(4)]);
                    % Set limits of axes
                    app.UIAxes.XLim = [0 I.XData(2)];
                    app.UIAxes.YLim = [0 I.YData(2)]; 
                end
            elseif (app.plac>app.ubm1 && app.smiech<app.ubm2)
                   pocitadlo1=pocitadlo1+1; %bol to plac
                   vypis{3,1}='It was the sound of crying';
                   app.TextArea.Value=vypis;
                   title(app.UIAxes, []);
                   xlabel(app.UIAxes, []);
                   ylabel(app.UIAxes, []);
                   app.UIAxes.XAxis.TickLabels = {};
                   app.UIAxes.YAxis.TickLabels = {};
                   % Display image and stretch to fill axes
                   I = imshow('maxresdefault.jpg', 'Parent', app.UIAxes, ...
                    'XData', [1 app.UIAxes.Position(3)], ...
                    'YData', [1 app.UIAxes.Position(4)]);
                   % Set limits of axes
                   app.UIAxes.XLim = [0 I.XData(2)];
                   app.UIAxes.YLim = [0 I.YData(2)];   
            elseif (app.plac<app.ubm1 && app.smiech>app.ubm2)
                   pocitadlo2=pocitadlo2+1; %bol to smiech
                   vypis{3,1}='It was the sound of laugh';
                   app.TextArea.Value=vypis;
                   title(app.UIAxes, []);
                   xlabel(app.UIAxes, []);
                   ylabel(app.UIAxes, []);
                   app.UIAxes.XAxis.TickLabels = {};
                   app.UIAxes.YAxis.TickLabels = {};
                   % Display image and stretch to fill axes
                   I = imshow('laughing-baby_postcard.jpg', 'Parent', app.UIAxes, ...
                    'XData', [1 app.UIAxes.Position(3)], ...
                    'YData', [1 app.UIAxes.Position(4)]);
                   % Set limits of axes
                   app.UIAxes.XLim = [0 I.XData(2)];
                   app.UIAxes.YLim = [0 I.YData(2)];
            elseif (app.plac<app.ubm1 && app.smiech<app.ubm2)
                   pocitadlo3=pocitadlo3+1;  %nebolo to nic
                   vypis{3,1}='The system cannot recognize the sound.';
                   app.TextArea.Value=vypis;
                       
                   title(app.UIAxes, []);
                   xlabel(app.UIAxes, []);
                   ylabel(app.UIAxes, []);
                   app.UIAxes.XAxis.TickLabels = {};
                   app.UIAxes.YAxis.TickLabels = {};
                   % Display image and stretch to fill axes
                   I = imshow('bielePozadie.png', 'Parent', app.UIAxes, ...
                    'XData', [1 app.UIAxes.Position(3)], ...
                    'YData', [1 app.UIAxes.Position(4)]);
                   % Set limits of axes
                   app.UIAxes.XLim = [0 I.XData(2)];
                   app.UIAxes.YLim = [0 I.YData(2)];   
              end
            clear plac ubm1 smiech ubm2 
            clear -regexp \d
            app.ButtonPLOT.Enable='on';
    
            end


            [app.x,app.fs]=audioread('audio.wav');
            app.player = audioplayer(app.x, app.fs); % pre PLAY
            app.flagg=2; % pre play  
            
            clear -regexp \d
        end

        % Value changed function: ButtonPLOT
        function ButtonPLOTValueChanged(app, event)
            value = app.ButtonPLOT.Value;
            
            fig = uifigure('Name','Plotted Results');
            %app.UIFigure.Position=[100 100 800 477];
            
            
            fig.Position=[490 100 420 477];
            p = uipanel(fig,'Position',[10 10 400 400]);
            ax = uiaxes(p,'Position',[10 10 390 390]);
            ax.XLabel.String = {'čas[s]'};
            
            
            t2=(0:length(app.x)-1)*(1/app.fs);
            
            plot(ax,t2,app.x);
            
        end

        % Value changed function: ButtonPlay
        function ButtonPlayValueChanged(app, event)
            value = app.ButtonPlay.Value;
            
            %app.ButtonStop.Enable='on';
            
          
            if(app.flagg==2)
                
                app.flagg=1;
               
           
    play(app.player);
                       
                     
                else
            if(app.flagg == 1)
             
        %disp('1');
        app.flagg=0;
        pause(app.player);
            else
              
       %disp('0');
        app.flagg=1;
        resume(app.player)
                    
                   
            end
             
            end
            
             
        end

        % Value changed function: ButtonRecord
        function ButtonRecordValueChanged(app, event)
            app.ButtonPLOT.Enable='off';
            %value = app.ButtonRecord.Value;
            app.TextArea.Value={''};
            
             %inicializacia obrazku
             title(app.UIAxes, []);
                   xlabel(app.UIAxes, []);
                   ylabel(app.UIAxes, []);
                   app.UIAxes.XAxis.TickLabels = {};
                   app.UIAxes.YAxis.TickLabels = {};
                   % Display image and stretch to fill axes
                   I = imshow('bielePozadie.png', 'Parent', app.UIAxes, ...
                    'XData', [1 app.UIAxes.Position(3)], ...
                    'YData', [1 app.UIAxes.Position(4)]);
                   % Set limits of axes
                   app.UIAxes.XLim = [0 I.XData(2)];
                   app.UIAxes.YLim = [0 I.YData(2)];
            
                   
            myRecObj = audiorecorder(44100,16, 1); % 44,1 kHz, 16-bit depth, 1 channel
            
            
            % na 5 sekund
            vypis{1,1}='Start of recording ..';
            app.TextArea.Value=vypis{1,1};
            recordblocking(myRecObj, 5.4);
            
            vypis{2,1}='..the end of recording';
            app.TextArea.Value=vypis{2,1};
            
            fs1=myRecObj.SampleRate;
            app.y = getaudiodata(myRecObj);
            
            [P,Q] = rat(16e3/fs1);
            app.x = resample(app.y,P,Q);
            
            app.x= app.x(6400:86400-1);
        
            %app.fs=myRecObj.SampleRate;
            
            audiowrite('audio.wav',app.x,16e3)
            
            [app.x,app.fs]=audioread('audio.wav');
            app.player = audioplayer(app.x, app.fs); % pre PLAY
            app.flagg=2; % pre play
            
            %sound(x,fs)
            P1=(sum(abs(app.x).^2))*(1/length(app.x));
            
                if P1<=1e-5
                    vypis{3,1}='The system cannot recognize the sound.';
                    app.TextArea.Value=vypis;
                else
            
                    test_features=melcepst(app.x,16000,'dD');
                    [X,test_features1,stredna_hodnota,smerodajna_odchylka] = CMN(test_features);
                    
                    load('celkovy_plac_32.mat');
                    likelihoodgm1 = gmmprob(gmm_adapt1,test_features1);
            
                    load('celkovy_smiech_32.mat');
                    likelihoodgm2 = gmmprob(gmm_adapt1,test_features1);
           
            clear gmm_adapt1
            
                    load('gmm_UBM_32.mat')
                    likelihoodgm3 = gmmprob(gm1_UBM,test_features1);
                      
            
                    pomer=(likelihoodgm1./likelihoodgm3);
     
               
                    app.plac=0;
                    app.ubm1=0;
                    for i=1:length(pomer)
                        if pomer(i)>=1.7333
                            app.plac=app.plac+1;
                        else
                            app.ubm1=app.ubm1+1;
        
                        end
                    end
              
                    app.plac=(app.plac/length(pomer))*100;
                    app.ubm1=(app.ubm1/length(pomer))*100;


                    clear pomer
            
                    pomer=(likelihoodgm2./likelihoodgm3);


                    app.smiech=0;
                    app.ubm2=0;
                    for i=1:length(pomer)
                        if pomer(i)>=1.6714
                            app.smiech=app.smiech+1;
                        else
                            app.ubm2=app.ubm2+1;
        
                        end
                    end
              
                    app.smiech=(app.smiech/length(pomer))*100;
                    app.ubm2=(app.ubm2/length(pomer))*100;
            
            
                end 
            
        pocitadlo1=0;
        pocitadlo2=0;
        pocitadlo3=0;


            if (app.plac > app.ubm1) && (app.smiech > app.ubm2)
                if app.plac>app.smiech
                    pocitadlo1=pocitadlo1+1; %bol to plac
                    vypis{3,1}='It was the sound of crying';
                    app.TextArea.Value=vypis;
                    
                    title(app.UIAxes, []);
                    xlabel(app.UIAxes, []);
                    ylabel(app.UIAxes, []);
                    app.UIAxes.XAxis.TickLabels = {};
                    app.UIAxes.YAxis.TickLabels = {};
                    % Display image and stretch to fill axes
                    I = imshow('maxresdefault.jpg', 'Parent', app.UIAxes, ...
                        'XData', [1 app.UIAxes.Position(3)], ...
                        'YData', [1 app.UIAxes.Position(4)]);
                    % Set limits of axes
                    app.UIAxes.XLim = [0 I.XData(2)];
                    app.UIAxes.YLim = [0 I.YData(2)];
                else
                    pocitadlo2=pocitadlo2+1; %bol to smiech
                    vypis{3,1}='It was the sound of laugh';
                    app.TextArea.Value=vypis;
                    
                    title(app.UIAxes, []);
                    xlabel(app.UIAxes, []);
                    ylabel(app.UIAxes, []);
                    app.UIAxes.XAxis.TickLabels = {};
                    app.UIAxes.YAxis.TickLabels = {};
                    % Display image and stretch to fill axes
                    I = imshow('laughing-baby_postcard.jpg', 'Parent', app.UIAxes, ...
                    'XData', [1 app.UIAxes.Position(3)], ...
                    'YData', [1 app.UIAxes.Position(4)]);
                    % Set limits of axes
                    app.UIAxes.XLim = [0 I.XData(2)];
                    app.UIAxes.YLim = [0 I.YData(2)]; 
                end
            elseif (app.plac>app.ubm1 && app.smiech<app.ubm2)
                   pocitadlo1=pocitadlo1+1; %bol to plac
                   vypis{3,1}='It was the sound of crying';
                   app.TextArea.Value=vypis;
                   title(app.UIAxes, []);
                   xlabel(app.UIAxes, []);
                   ylabel(app.UIAxes, []);
                   app.UIAxes.XAxis.TickLabels = {};
                   app.UIAxes.YAxis.TickLabels = {};
                   % Display image and stretch to fill axes
                   I = imshow('maxresdefault.jpg', 'Parent', app.UIAxes, ...
                    'XData', [1 app.UIAxes.Position(3)], ...
                    'YData', [1 app.UIAxes.Position(4)]);
                   % Set limits of axes
                   app.UIAxes.XLim = [0 I.XData(2)];
                   app.UIAxes.YLim = [0 I.YData(2)];   
            elseif (app.plac<app.ubm1 && app.smiech>app.ubm2)
                   pocitadlo2=pocitadlo2+1; %bol to smiech
                   vypis{3,1}='It was the sound of laugh';
                   app.TextArea.Value=vypis;
                   title(app.UIAxes, []);
                   xlabel(app.UIAxes, []);
                   ylabel(app.UIAxes, []);
                   app.UIAxes.XAxis.TickLabels = {};
                   app.UIAxes.YAxis.TickLabels = {};
                   % Display image and stretch to fill axes
                   I = imshow('laughing-baby_postcard.jpg', 'Parent', app.UIAxes, ...
                    'XData', [1 app.UIAxes.Position(3)], ...
                    'YData', [1 app.UIAxes.Position(4)]);
                   % Set limits of axes
                   app.UIAxes.XLim = [0 I.XData(2)];
                   app.UIAxes.YLim = [0 I.YData(2)];
            elseif (app.plac<app.ubm1 && app.smiech<app.ubm2)
                   pocitadlo3=pocitadlo3+1;  %nebolo to nic
                   vypis{3,1}='The system cannot recognize the sound.';
                   app.TextArea.Value=vypis;
                       
                   title(app.UIAxes, []);
                   xlabel(app.UIAxes, []);
                   ylabel(app.UIAxes, []);
                   app.UIAxes.XAxis.TickLabels = {};
                   app.UIAxes.YAxis.TickLabels = {};
                   % Display image and stretch to fill axes
                   I = imshow('bielePozadie.png', 'Parent', app.UIAxes, ...
                    'XData', [1 app.UIAxes.Position(3)], ...
                    'YData', [1 app.UIAxes.Position(4)]);
                   % Set limits of axes
                   app.UIAxes.XLim = [0 I.XData(2)];
                   app.UIAxes.YLim = [0 I.YData(2)];    
            end
            clear plac ubm1 smiech ubm2 
            clear -regexp \d
            app.ButtonPLOT.Enable='on';
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.ButtonPlay = uibutton(app.UIFigure, 'state');
            app.ButtonPlay.ValueChangedFcn = createCallbackFcn(app, @ButtonPlayValueChanged, true);
            app.ButtonPlay.Icon = '06.png';
            app.ButtonPlay.Text = '';
            app.ButtonRecord = uibutton(app.UIFigure, 'state');
            app.ButtonRecord.ValueChangedFcn = createCallbackFcn(app, @ButtonRecordValueChanged, true);
            app.ButtonRecord.Icon = 'audio-mic-microphone-31d635cc6171e7b7-512x512.png';
            app.ButtonRecord.Text = '';
            app.ButtonPLOT = uibutton(app.UIFigure, 'state');
            app.ButtonPLOT.ValueChangedFcn = createCallbackFcn(app, @ButtonPLOTValueChanged, true);
            app.ButtonPLOT.Icon = 'sine-plot-flat-icon-vector-16203055.jpg';
            app.ButtonPLOT.Text = '';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.ButtonExit = uibutton(app.UIFigure, 'state');
            app.ButtonExit.ValueChangedFcn = createCallbackFcn(app, @ButtonExitValueChanged, true);
            app.ButtonExit.Icon = '05.png';
            app.ButtonExit.Text = '';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'čas [s]')
            ylabel(app.UIAxes2, 'Y')
            app.ButtonOpenFile = uibutton(app.UIFigure, 'state');
            app.ButtonOpenFile.ValueChangedFcn = createCallbackFcn(app, @ButtonOpenFileValueChanged, true);
            app.ButtonOpenFile.Icon = 'folder-open-outline.png';
            app.ButtonOpenFile.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Aplikacia

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end